classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        ResetButton     matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        Button2         matlab.ui.control.Button
        Button3         matlab.ui.control.Button
        ArmJointsLabel  matlab.ui.control.Label
        SolveButton     matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        UIAxes          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        [X,Y, Z] = cylinder2P(2.1, 40, [-10, 0, 0], [10,0,0]);
        surf(app.UIAxes, X,Y,Z, 'FaceColor', [0 0.4470 0.7410], 'FaceAlpha', 0.1, 'EdgeColor', 'none');
        
        app.UIAxes.CameraUpVector = [-1 0 0];
        app.UITable.Data = zeros(1,numel(app.UITable.ColumnName)); 
        end

        % Button pushed function: Button2
        function Button2ButtonPushed(app, event)
            app.UITable.Data(end+1,:) = 0;
            
            num_of_rows = size(app.UITable.Data,1);
            rownames = cell(num_of_rows,1);
            
            for i=1:size(app.UITable.Data, 1)
                rownames{i} = "Joint " + num2str(i);
            end
            
            app.UITable.RowName =  rownames;
        end

        % Button pushed function: Button3
        function Button3ButtonPushed(app, event)
            app.UITable.Data(end,:) = [];
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
           [X,Y, Z] = cylinder2P(2.1, 40, [-10, 0, 0], [10,0,0]);
           surf(app.UIAxes, X,Y,Z, 'FaceColor', [0 0.4470 0.7410], 'FaceAlpha', 0.1, 'EdgeColor', 'none');
           app.UIAxes.CameraUpVector = [-1 0 0];
           hold(app.UIAxes,'on') 
           arm_solve_backend(app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {650, 650};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {511, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {''; ''};
            app.UITable.ColumnEditable = true;
            app.Button2 = uibutton(app.LeftPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed, true);
            app.Button2.Text = '+';
            app.Button3 = uibutton(app.LeftPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3ButtonPushed, true);
            app.Button3.Text = '-';
            app.ArmJointsLabel = uilabel(app.LeftPanel);
            app.ArmJointsLabel.Text = 'Arm Joints';
            app.SolveButton = uibutton(app.LeftPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-8 8];
            app.UIAxes.YLim = [-4 4];
            app.UIAxes.ZLim = [-4 4];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end